classdef Task_4_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        input_sp_end               matlab.ui.control.NumericEditField
        EditField_10Label          matlab.ui.control.Label
        input_sp_steps             matlab.ui.control.NumericEditField
        EditField_9Label           matlab.ui.control.Label
        input_sp_start             matlab.ui.control.NumericEditField
        SPEditFieldLabel           matlab.ui.control.Label
        button_load                matlab.ui.control.Button
        button_save                matlab.ui.control.Button
        button_simulate            matlab.ui.control.Button
        input_tinsp                matlab.ui.control.NumericEditField
        tinspsEditFieldLabel       matlab.ui.control.Label
        input_n                    matlab.ui.control.NumericEditField
        NEditFieldLabel            matlab.ui.control.Label
        input_q                    matlab.ui.control.NumericEditField
        QmLsEditFieldLabel         matlab.ui.control.Label
        input_r                    matlab.ui.control.NumericEditField
        RmLsmbarEditFieldLabel     matlab.ui.control.Label
        input_k                    matlab.ui.control.NumericEditField
        K1mbarEditFieldLabel       matlab.ui.control.Label
        input_top                  matlab.ui.control.NumericEditField
        TOPmbarEditFieldLabel      matlab.ui.control.Label
        input_cfrc                 matlab.ui.control.NumericEditField
        C_FRCmLmbarEditFieldLabel  matlab.ui.control.Label
        input_cl                   matlab.ui.control.NumericEditField
        CLmLmbarEditFieldLabel     matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    properties (Access = private)
        data_new % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: input_sp_end
        function input_sp_endValueChanged(app, event)
            
        end

        % Value changed function: input_sp_steps
        function input_sp_stepsValueChanged(app, event)

        end

        % Value changed function: input_sp_start
        function input_sp_startValueChanged(app, event)
            
        end

        % Button pushed function: button_load
        function button_loadButtonPushed(app, event)
            [fileName,pathName] = uigetfile('.mat','Select Patient file');
            filePath = fullfile(pathName,fileName);
            app.data_new = load(filePath);

            app.input_top.Value = app.data_new.parameters.TOP;
            app.input_k.Value = app.data_new.parameters.K;
            app.input_n.Value = app.data_new.parameters.N;
            app.input_sp_start.Value = app.data_new.parameters.SP(1);
            app.input_sp_steps.Value = app.data_new.parameters.SP(2) - app.data_new.parameters.SP(1);
            % or:
            % app.input_sp_steps.Value = (app.data_new.parameters.SP(end)-app.data_new.parameters.SP(1))/(length(app.data_new.parameters.SP)-1)
            app.input_sp_end.Value = app.data_new.parameters.SP(end);
            app.input_cfrc.Value = app.data_new.parameters.Cfrc;
            app.input_cl.Value = app.data_new.parameters.CL;
            app.input_r.Value = app.data_new.parameters.R;
            app.input_q.Value = app.data_new.parameters.Q;
            app.input_tinsp.Value = app.data_new.parameters.tinsp;
            
            app.input_sp_end.Enable = "on";   
            app.input_sp_steps.Enable = "on";   
            app.input_sp_start.Enable = "on";  
            app.button_save.Enable = "on";
            app.button_simulate.Enable = "on";
            app.input_tinsp.Enable = "on";
            app.input_n.Enable = "on";
            app.input_q.Enable = "on";
            app.input_r.Enable = "on";
            app.input_k.Enable = "on";
            app.input_top.Enable = "on";
            app.input_cfrc.Enable = "on";
            app.input_cl.Enable = "on";
        end

        % Button pushed function: button_save
        function button_saveButtonPushed(app, event)
            [fileName,pathName] = uiputfile('.mat','Set filename');
            filePath = fullfile(pathName,fileName);
            if (length(filePath)>3)
            %here you have to collect all parameter values from the GUI
            %and put them in a structure named “parameters”
                parameters.TOP = app.input_top.Value;
                parameters.K = app.input_k.Value;
                parameters.N = app.input_n.Value;
                parameters.SP = app.input_sp_start.Value:app.input_sp_steps.Value:app.input_sp_end.Value;
                parameters.Cfrc = app.input_cfrc.Value;
                parameters.CL = app.input_cl.Value;
                parameters.R = app.input_r.Value;
                parameters.Q = app.input_q.Value;
                parameters.tinsp = app.input_tinsp.Value;
            
                save(filePath,'parameters');
            end
        end

        % Button pushed function: button_simulate
        function button_simulateButtonPushed(app, event)
            if (app.input_n.Value > length(app.input_sp_start.Value:app.input_sp_steps.Value:app.input_sp_end.Value))
                app.input_n.Value = length(app.input_sp_start.Value:app.input_sp_steps.Value:app.input_sp_end.Value);
            end
            [t,my_palv]=ode45(@(t,my_P) app.input_q.Value./Task_1(app.input_cfrc.Value, app.input_k.Value,...
                app.input_cl.Value, (app.input_sp_start.Value:app.input_sp_steps.Value:app.input_sp_end.Value),...
                app.input_top.Value, app.input_n.Value, my_P),0:0.05:app.input_tinsp.Value,0);
            my_paw = app.input_r.Value * app.input_q.Value + my_palv;
            
            plot(app.UIAxes, t, my_paw, 'k', 'LineWidth', 2)
            set(app.UIAxes,'XGrid','on');
            set(app.UIAxes,'YGrid','on');
            set(app.UIAxes,'xLim',[min(0) max(t)]);
            set(app.UIAxes,'YLim',[min(0) max(max(my_paw))]);
        end

        % Value changed function: input_tinsp
        function input_tinspValueChanged(app, event)
            
        end

        % Value changed function: input_n
        function input_nValueChanged(app, event)
            % Limitation
             app.input_n.Limits = [0,length(app.input_sp_start.Value:app.input_sp_steps.Value:app.input_sp_end.Value)];
        end

        % Value changed function: input_q
        function input_qValueChanged(app, event)
            
        end

        % Value changed function: input_r
        function input_rValueChanged(app, event)
            
        end

        % Value changed function: input_k
        function input_kValueChanged(app, event)
            
        end

        % Value changed function: input_top
        function input_topValueChanged(app, event)
            
        end

        % Value changed function: input_cfrc
        function input_cfrcValueChanged(app, event)
            
        end

        % Value changed function: input_cl
        function input_clValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'airway pressure')
            xlabel(app.UIAxes, 'time [s]')
            ylabel(app.UIAxes, 'pressure [mbar]')
            zlabel(app.UIAxes, 'Z')
            app.CLmLmbarEditFieldLabel = uilabel(app.UIFigure);
            app.CLmLmbarEditFieldLabel.Enable = 'off';
            app.CLmLmbarEditFieldLabel.Text = 'CL [mL/mbar]';
            app.input_cl = uieditfield(app.UIFigure, 'numeric');
            app.input_cl.Limits = [0.1 19];
            app.input_cl.ValueChangedFcn = createCallbackFcn(app, @input_clValueChanged, true);
            app.input_cl.Enable = 'off';
            app.input_cl.Value = 0.1;
            app.C_FRCmLmbarEditFieldLabel = uilabel(app.UIFigure);
            app.C_FRCmLmbarEditFieldLabel.Enable = 'off';
            app.C_FRCmLmbarEditFieldLabel.Text = 'C_FRC [mL/mbar]';
            app.input_cfrc = uieditfield(app.UIFigure, 'numeric');
            app.input_cfrc.Limits = [1 199];
            app.input_cfrc.ValueChangedFcn = createCallbackFcn(app, @input_cfrcValueChanged, true);
            app.input_cfrc.Enable = 'off';
            app.input_cfrc.Value = 1;
            app.TOPmbarEditFieldLabel = uilabel(app.UIFigure);
            app.TOPmbarEditFieldLabel.Enable = 'off';
            app.TOPmbarEditFieldLabel.Text = 'TOP [mbar]';
            app.input_top = uieditfield(app.UIFigure, 'numeric');
            app.input_top.Limits = [0.1 99];
            app.input_top.ValueChangedFcn = createCallbackFcn(app, @input_topValueChanged, true);
            app.input_top.Enable = 'off';
            app.input_top.Value = 0.1;
            app.K1mbarEditFieldLabel = uilabel(app.UIFigure);
            app.K1mbarEditFieldLabel.Enable = 'off';
            app.K1mbarEditFieldLabel.Text = 'K [1/mbar]';
            app.input_k = uieditfield(app.UIFigure, 'numeric');
            app.input_k.Limits = [0.01 4.99];
            app.input_k.ValueChangedFcn = createCallbackFcn(app, @input_kValueChanged, true);
            app.input_k.Enable = 'off';
            app.input_k.Value = 0.01;
            app.RmLsmbarEditFieldLabel = uilabel(app.UIFigure);
            app.RmLsmbarEditFieldLabel.Enable = 'off';
            app.RmLsmbarEditFieldLabel.Text = 'R [mL*s/mbar]';
            app.input_r = uieditfield(app.UIFigure, 'numeric');
            app.input_r.Limits = [0.01 4.99];
            app.input_r.ValueChangedFcn = createCallbackFcn(app, @input_rValueChanged, true);
            app.input_r.Enable = 'off';
            app.input_r.Value = 0.01;
            app.QmLsEditFieldLabel = uilabel(app.UIFigure);
            app.QmLsEditFieldLabel.Enable = 'off';
            app.QmLsEditFieldLabel.Text = 'Q [mL/s]';
            app.input_q = uieditfield(app.UIFigure, 'numeric');
            app.input_q.Limits = [10 1999];
            app.input_q.ValueChangedFcn = createCallbackFcn(app, @input_qValueChanged, true);
            app.input_q.Enable = 'off';
            app.input_q.Value = 10;
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Enable = 'off';
            app.NEditFieldLabel.Text = 'N';
            app.input_n = uieditfield(app.UIFigure, 'numeric');
            app.input_n.Limits = [0 Inf];
            app.input_n.ValueChangedFcn = createCallbackFcn(app, @input_nValueChanged, true);
            app.input_n.Enable = 'off';
            app.tinspsEditFieldLabel = uilabel(app.UIFigure);
            app.tinspsEditFieldLabel.Enable = 'off';
            app.tinspsEditFieldLabel.Text = 'tinsp [s]';
            app.input_tinsp = uieditfield(app.UIFigure, 'numeric');
            app.input_tinsp.Limits = [0.01 99];
            app.input_tinsp.ValueChangedFcn = createCallbackFcn(app, @input_tinspValueChanged, true);
            app.input_tinsp.Enable = 'off';
            app.input_tinsp.Value = 0.01;
            app.button_simulate = uibutton(app.UIFigure, 'push');
            app.button_simulate.ButtonPushedFcn = createCallbackFcn(app, @button_simulateButtonPushed, true);
            app.button_simulate.Enable = 'off';
            app.button_simulate.Text = 'Simulate';
            app.button_save = uibutton(app.UIFigure, 'push');
            app.button_save.ButtonPushedFcn = createCallbackFcn(app, @button_saveButtonPushed, true);
            app.button_save.Enable = 'off';
            app.button_save.Text = 'Save';
            app.button_load = uibutton(app.UIFigure, 'push');
            app.button_load.ButtonPushedFcn = createCallbackFcn(app, @button_loadButtonPushed, true);
            app.button_load.Text = 'Load';
            app.SPEditFieldLabel = uilabel(app.UIFigure);
            app.SPEditFieldLabel.Enable = 'off';
            app.SPEditFieldLabel.Text = 'SP';
            app.input_sp_start = uieditfield(app.UIFigure, 'numeric');
            app.input_sp_start.Limits = [0 1];
            app.input_sp_start.ValueChangedFcn = createCallbackFcn(app, @input_sp_startValueChanged, true);
            app.input_sp_start.Enable = 'off';
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Enable = 'off';
            app.EditField_9Label.Text = ':';
            app.input_sp_steps = uieditfield(app.UIFigure, 'numeric');
            app.input_sp_steps.Limits = [0 5];
            app.input_sp_steps.ValueChangedFcn = createCallbackFcn(app, @input_sp_stepsValueChanged, true);
            app.input_sp_steps.Enable = 'off';
            app.EditField_10Label = uilabel(app.UIFigure);
            app.EditField_10Label.Enable = 'off';
            app.EditField_10Label.Text = ':';
            app.input_sp_end = uieditfield(app.UIFigure, 'numeric');
            app.input_sp_end.Limits = [0 99];
            app.input_sp_end.ValueChangedFcn = createCallbackFcn(app, @input_sp_endValueChanged, true);
            app.input_sp_end.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Task_4_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end